classdef laiiqautilities_win64 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ArchivoMenu                matlab.ui.container.Menu
        NuevoMenu                  matlab.ui.container.Menu
        CineticaOzonizacionMenu    matlab.ui.container.Menu
        VisorOzonogramaMenu        matlab.ui.container.Menu
        VisorEspectroUVVisMenu     matlab.ui.container.Menu
        BiorreaccionMenu           matlab.ui.container.Menu
        ReporteMenu                matlab.ui.container.Menu
        AbrirMenu                  matlab.ui.container.Menu
        OzonogramaMenu             matlab.ui.container.Menu
        EspectroUVVisMenu          matlab.ui.container.Menu
        BiorreaccionMenu2          matlab.ui.container.Menu
        GuardarMenu                matlab.ui.container.Menu
        GuardarcomoMenu            matlab.ui.container.Menu
        SalirMenu                  matlab.ui.container.Menu
        TabGroup                   matlab.ui.container.TabGroup
        CineticaOzonizacionTab     matlab.ui.container.Tab
        CineticaButton             matlab.ui.control.Button
        HTML1                      matlab.ui.control.HTML
        CrearnuevoLabel_14         matlab.ui.control.Label
        TabGroupCinetica           matlab.ui.container.TabGroup
        CineticaTab                matlab.ui.container.Tab
        MonitorLamp                matlab.ui.control.Lamp
        MonitorLabel               matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        ArduinoDropDownLabel       matlab.ui.control.Label
        ArduinoDropDown            matlab.ui.control.DropDown
        BuscarButton               matlab.ui.control.Button
        Panel_2                    matlab.ui.container.Panel
        UnidadesTiempoDropDown     matlab.ui.control.DropDown
        TiempoEditFieldLabel       matlab.ui.control.Label
        TiempoEditField            matlab.ui.control.NumericEditField
        GananciaSpinnerLabel       matlab.ui.control.Label
        GananciaSpinner            matlab.ui.control.Spinner
        Panel_3                    matlab.ui.container.Panel
        DisplayEditField           matlab.ui.control.NumericEditField
        IniciarButton              matlab.ui.control.StateButton
        gNm3Label                  matlab.ui.control.Label
        Panel_4                    matlab.ui.container.Panel
        GuardarCineticaButton      matlab.ui.control.Button
        Panel_5                    matlab.ui.container.Panel
        ConsumidoLabel             matlab.ui.control.Label
        ResidualLabel              matlab.ui.control.Label
        TotalLabel                 matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        DropDownCinetica           matlab.ui.control.DropDown
        UIAxes                     matlab.ui.control.UIAxes
        OzonogramaTab              matlab.ui.container.Tab
        OzonogramaButton           matlab.ui.control.Button
        CrearnuevoLabel_15         matlab.ui.control.Label
        HTML2                      matlab.ui.control.HTML
        TabGroupOzonograma         matlab.ui.container.TabGroup
        OzonogramaTab2             matlab.ui.container.Tab
        MonitorLamp_2              matlab.ui.control.Lamp
        MonitorLabel_2             matlab.ui.control.Label
        Panel_6                    matlab.ui.container.Panel
        GuardarOzonogramaButton    matlab.ui.control.Button
        Panel_7                    matlab.ui.container.Panel
        AbrirButton                matlab.ui.control.Button
        VersionButtonGroup         matlab.ui.container.ButtonGroup
        v2Button                   matlab.ui.control.RadioButton
        v3Button                   matlab.ui.control.RadioButton
        Panel_8                    matlab.ui.container.Panel
        ColordelineaDropDownLabel  matlab.ui.control.Label
        ColordelineaDropDown       matlab.ui.control.DropDown
        GridCheckBox               matlab.ui.control.CheckBox
        Panel_9                    matlab.ui.container.Panel
        ConsumidoLabel_2           matlab.ui.control.Label
        ResidualLabel_2            matlab.ui.control.Label
        TotalLabel_2               matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        DropDownOzonograma         matlab.ui.control.DropDown
        TitleEditField             matlab.ui.control.EditField
        TitleEditField_2           matlab.ui.control.EditField
        ArchivoDropDown            matlab.ui.control.DropDown
        UIAxes2                    matlab.ui.control.UIAxes
        EspectroUVTab              matlab.ui.container.Tab
        EspectroButton             matlab.ui.control.Button
        CrearnuevoLabel_16         matlab.ui.control.Label
        HTML3                      matlab.ui.control.HTML
        TabGroupEspectro           matlab.ui.container.TabGroup
        EspectroTab                matlab.ui.container.Tab
        BiorreaccionTab            matlab.ui.container.Tab
        BiorreaccionButton         matlab.ui.control.Button
        CrearnuevoLabel_17         matlab.ui.control.Label
        HTML4                      matlab.ui.control.HTML
        TabGroupBiorreaccion       matlab.ui.container.TabGroup
        BioreaccionTab             matlab.ui.container.Tab
        ReporteTab                 matlab.ui.container.Tab
        ReporteButton              matlab.ui.control.Button
        CrearnuevoLabel_18         matlab.ui.control.Label
        HTML5                      matlab.ui.control.HTML
        TabGroupReporte            matlab.ui.container.TabGroup
        ReporteTab5                matlab.ui.container.Tab
        InstruccionesTab           matlab.ui.container.Tab
        HTML7                      matlab.ui.control.HTML
        HTML6                      matlab.ui.control.HTML
        AcercadeTab                matlab.ui.container.Tab
        HTML9                      matlab.ui.control.HTML
        HTML8                      matlab.ui.control.HTML
    end

    
    properties (Access = private)
        t % Tiempo log Arduino
        oz % Concentración log Arduino
        
        a % Objeto Arduino
        ports % Puertos seriales encontrados
        
        flag
        datamatrix % Description
        f
        path
        
        residual1 % Ozono residual calculado cinetica
        consumido1 % Ozono consumido calculdo cinetica
        total1 % Total de ozono producido cinetica
        
        residual2 % Ozono residual calculado ozonograma
        consumido2 % Ozono consumido calculdo ozonograma
        total2 % Total de ozono producido ozonograma
        
        %         tab % Tabla para cargar archivos abiertos
        t_o % Tiempo log de archivo abierto
        oz_o % Concentración de archivo abierto
        tab
        fileversion % Versión de archivo para abrir
        ArchivoValue % Archivo seleccionado para graficar
    end
    
    methods (Access = public)
        
        %         function closeTab(app,src,event)
        %             delete(app.tg.SelectedTab)
        %         end


        function ArduinoData(app)
            %             if app.flag == 0
            app.DisplayEditField.Value = 0;
            app.flag = 0;
            try
                for i=1:app.TiempoEditField.Value*100
                    if app.IniciarButton.Value == 0 %|| isemty(app.a) == 1
                        app.IniciarButton.Enable = 'off';
                        app.IniciarButton.Text = 'Deteniendo...';
                        monitorSay(app,"#edb120",">> Deteniendo...","",3);
                        break;
                    end
                    app.t(i) = i/100;
                    app.oz(i) = app.a.analogRead(0)*(600/1023)*app.GananciaSpinner.Value;
                    app.DisplayEditField.Value = app.oz(i);
                    plot(app.UIAxes,app.t,app.oz);
                    pause(0.6);
                end
            catch
                app.IniciarButton.Enable = 'off';
                warning("Arduino desconectado, proceso interrumpido!");
                monitorSay(app,"red",">> Arduino desconectado, proceso interrumpido!","",3);
                app.IniciarButton.Text = 'Deteniendo...';
                app.IniciarButton.Enable = 'off';
                app.BuscarButton.Enable = "on";
                app.ArduinoDropDown.Enable = 'on';
                app.TiempoEditField.Enable = 'on';
                app.GuardarCineticaButton.Enable = 'on';
                app.UnidadesTiempoDropDown.Enable = 'on';
                app.IniciarButton.Text = 'Iniciar';
                clear app.a
                app.a = [];
                app.flag = 1;
                %                 monitorSay(app,"green",">> Listo!","",1.5);
            end
            try
                app.datamatrix = [app.t', round(app.oz'*100)/100];
                %                 disp(app.datamatrix)
            catch
                warning("Corrigiendo errores...");
                monitorSay(app,"#edb120",">> Corrigiendo errores...","",2);
                app.t(end) = [];
                app.datamatrix = [app.t', round(app.oz'*100)/100];
                %                 disp("Datos Obtenidos:")
                %                 disp(app.t')
                %                 disp(app.oz')
            end
            monitorSay(app,"#edb120","",">> Preparando datos, espere...",1);
            [app.residual1,app.consumido1,app.total1] = calculateOzone(app,app.t,app.oz);
            % app.tab = table(app.t, app.oz);
            % er1 = [app.t; app.oz];
            %             assignin('base', 'data', app.datamatrix)
            % assignin('base', 'data', app.data)
            % assignin('base', 'e1', er1)
            %             assignin('base', 'time', app.t)
            %             assignin('base', 'conc', app.oz)
            %             disp(app.data)
            % funcion para guardar archivos
            % saveDataFunc(app, app.t, app.oz);
            %             pause(1)
            %             app.IniciarButton.Text = 'Listo!';
            
            monitorSay(app,"green",">> Listo!","",2); % Monitor
            
            app.GuardarCineticaButton.Enable = 'on';
            if app.flag == 1
                app.IniciarButton.Enable = 'off';
            else
                app.IniciarButton.Enable = 'on';
            end
            app.IniciarButton.Value = 0;
            app.IniciarButton.Text = 'Iniciar';
            app.BuscarButton.Enable = "on";
            app.ArduinoDropDown.Enable = 'on';
            app.TiempoEditField.Enable = 'on';
            app.DropDownCinetica.Enable = "on";
            app.UnidadesTiempoDropDown.Enable = 'on';
            DropDownCineticaValueChanged(app);
            BuscarButtonPushed(app);
            pause(3)
        end


        % Función para el monitor en la app
        function monitorSay(app,color,message1,message2,time)
            app.MonitorLamp.Color = color;
            app.MonitorLabel.FontColor = color;
            app.MonitorLabel.Text = message1;
            pause(time)
            app.MonitorLabel.Text = message2;
            
        end


        % Función para el monitor en la app
        function monitorSay2(app,color,message1,message2,time)
            app.MonitorLamp_2.Color = color;
            app.MonitorLabel_2.FontColor = color;
            app.MonitorLabel_2.Text = message1;
            pause(time)
            app.MonitorLabel_2.Text = message2;
            
        end


        function [residual, consumido, total] = calculateOzone(app,time,conc)
            if isequal(time,0)
                residual = app.residual2;
                consumido = app.consumido2;
                total = app.total2;
            else
                residual = trapz(time,conc);
                consumido = max(time)*max(conc) - residual;
                total = residual + consumido;
                %             if app.DropDownCinetica.Value == "g/Nm^3"
                %                 app.Label.Text = num2str(app.consumido_calc,"%.2f");
                %                 app.Label_2.Text = num2str(app.residual_calc,"%.2f");
                %                 app.Label_3.Text = num2str(app.total_calc,"%.2f");
                %             end
                %             if app.DropDownCinetica.Value == "g/L"
                %                 app.residual_calc = app.residual_calc/1000;
                %                 app.consumido_calc = app.consumido_calc/1000;
                %                 app.total_calc = app.total_calc/1000;
                %                 app.Label.Text = num2str(app.consumido_calc,"%.3f");
                %                 app.Label_2.Text = num2str(app.residual_calc,"%.3f");
                %                 app.Label_3.Text = num2str(app.total_calc,"%.3f");
                %             end
                disp(strcat("Residual = " + residual))
                disp(strcat("Consumido = " + consumido))
                disp(strcat("Total = " + total))
            end
        end


        function GuardarArchivo(app, t, oz, res_calc, con_calc, tot_calc)
            time = t;
            conc = oz;
            residual = res_calc;
            consumido = con_calc;
            total = tot_calc;
            datmatrix = [round(time'*100)/100, round(conc'*100)/100];
            app.f = 0;
            app.path = 0;
            % considerar la siguiente forma:
            [app.f,app.path,index] = uiputfile({'*.mat', 'MAT-files (*.mat)'; '*.xlsx', 'Excel (*.xlsx)'; '*.csv', 'Archivo CSV (*.csv)'; '*.txt', 'Archivo de texto (*.txt)'}, 'Guardar como');
            if isequal(app.f,0) || isequal(app.path,0)
                %                 disp('User clicked Cancel.')
                monitorSay(app,"green",">> Ok!","",2);
                app.GuardarCineticaButton.Enable = 'on';
                monitorSay2(app,"green",">> Ok!","",2);
                app.GuardarOzonogramaButton.Enable = 'on';
            else
                % disp(['User selected ',fullfile(path,f),...
                % and then clicked Save.'])
                if index == 1
                    data = [time;conc];
                    save(fullfile(app.path, app.f), 'data', 'consumido', 'residual', 'total', '-mat', '-v7.3');
                elseif index == 2
                    format short
                    xlswrite(fullfile(app.path,app.f), {'Ozono', app.DropDownCinetica.Value; 'Consumido: ', consumido; 'Residual: ', residual; 'Total: ', total}, 1, 'A1');
                    xlswrite(fullfile(app.path,app.f), {'Tiempo (min)', 'Concentracion (g/Nm^3)'}, 1, 'A6');
                    xlswrite(fullfile(app.path,app.f), datmatrix, 1, 'A7');
                    %                     xlswrite(fullfile(app.path,app.f), app.t', 1, 'A7');
                    %                     xlswrite(fullfile(app.path,app.f),round(app.oz'*100)/100,1,'B7');
                    %                     writematrix(app.datamatrix, fullfile(path,f), "WriteMode","append");
                elseif index == 3 || index == 4
                    fileID = fopen(fullfile(app.path,app.f), 'w');
                    fprintf(fileID,"Ozono, %s\n",app.DropDownCinetica.Value);
                    fprintf(fileID,"Consumido:, %.2f\n", consumido);
                    fprintf(fileID,"Residual:, %.2f\n", residual);
                    fprintf(fileID,"Total:, %.2f\n", total);
                    fprintf(fileID,"\n");
                    fprintf(fileID,"Tiempo (min), Concentracion (g/Nm^3)\n");
                    writematrix(datmatrix, fullfile(app.path, app.f), "WriteMode","append");
                    fclose(fileID);
                end
                app.GuardarCineticaButton.Enable = 'on';
                monitorSay(app,"green",">> Listo!","",2);
                app.GuardarOzonogramaButton.Enable = 'on';
                monitorSay2(app,"green",">> Listo!","",2);
            end
            % tomado de https://www.mathworks.com/help/matlab/ref/uiputfile.html
            % [filename, pathname, filterindex] = uiputfile( ...
            % {'*.m;*.fig;*.mat;*.slx;*.mdl',...
            %  'MATLAB Files (*.m,*.mlx,*.fig,*.mat,*.slx,*.mdl)';
            %  '*.m;*.mlx', 'program files (*.m,*.mlx)';...
            %  '*.fig','Figures (*.fig)';...
            %  '*.mat','MAT-files (*.mat)';...
            %  '*.slx;*.mdl','Models (*.slx,*.mdl)';...
            %  '*.*',  'All Files (*.*)'});
            % saveDataFunc(app, app.t, app.oz, app.area_sc, app.area_bc, app.area_t);
        end


        % ----> TODO: Convertir datos de tabla en columnas
        function [tab] = AbrirArchivo(app)
            app.f = 0;
            app.path = 0;
%             VersionButtonGroupSelectionChanged(app);
            [app.f, app.path] = uigetfile({'*.mat'},'Seleccionar Archivo', 'MultiSelect','on');
            if isequal(app.f,0)
                monitorSay2(app,"green",">> Ok!","",2);
%                 time = 0;
%                 conc = 0;
%                 tab = 0;
            else
%                 time = 0;
%                 conc = 0;
                % tab = 0;
                tab = table();
                if length(string(app.f)) == 1
                    varlist = who('-file', fullfile(app.path, app.f));
                    if length(varlist) > 1
                        dt = importdata(fullfile(app.path, app.f));
%                         time = dt.('data')(1,:);
%                         conc = dt.('data')(2,:);
%                         time = 1:length(conc);
%                         if app.fileversion.Text == "v2"
%                             time = time/6000;
%                         else
%                             time = time/100;
%                         end
%                         tab.tiempo = time;
                        tab.(app.file) = dt.('data')(2,:);
                    else
                        dt = importdata(fullfile(app.path, app.f));
%                         time = dt(1,:);
%                         conc = dt(2,:);
%                         time = 1:length(conc);
%                         if app.fileversion.Text == "v2"
%                             time = time/6000;
%                         else
%                             time = time/100;
%                         end
%                         tab.tiempo = time;
                        tab.(app.f) = dt(2,:);
                    end
                    app.ArchivoDropDown.Items = [string(app.f)];
                    % plot(app.UIAxes2,tab.tiempo,tab.concentracion);
                    
                elseif length(string(app.f)) > 1
                    plot(app.UIAxes2, 0);
                    app.ArchivoDropDown.Items = ["Todos"];
                    % hold(app.UIAxes2, 'on')
                    for i=1:length(string(app.f))
                        varlist = who('-file', fullfile(app.path, app.f{i}));
                        if length(varlist) > 1
                            dt = importdata(fullfile(app.path, app.f{i}));
                            time = dt.('data')(1,:);
                            conc = dt.('data')(2,:);
                            if app.fileversion.Text == "v2"
                                time = time/60;
                            else
                                % Nada
                            end
                            tab.tiempo = time;
                            tab.(app.f{i}) = conc;
                        else
                            dt = importdata(fullfile(app.path, app.f{i}));
                            time = dt(1,:);
                            conc = dt(2,:);
                            if app.fileversion.Text == "v2"
                                time = time/60;
                            else
                                % Nada
                            end
                            tab.tiempo = time;
                            tab.(app.f{i}) = conc;
                        end
                        app.ArchivoDropDown.Items = [app.ArchivoDropDown.Items app.f{i}];
                        % timelenght = length(tab.(app.f{i}));
                        % plot(app.UIAxes2,tab.tiempo,tab.(app.f{i}));
                    end
%                     hold(app.UIAxes2, 'off');
                end
                plotArchivos(app);
                monitorSay2(app,"green",">> Listo!","",2);
            end
        end


        function plotArchivos(app, x, y)
            % ---> TODO: Sumar los calculos de ozono en el ciclo for
            
            % ---> TODO: Hacer el calculo de ozono para un solo archivo
            ArchivoDropDownValueChanged(app);
            VersionButtonGroupSelectionChanged(app);
            app.residual2 = 0;
            app.consumido2 = 0;
            app.total2 = 0;
            if value == "Todos"
                plot(app.UIAxes2, 0);
                app.UIAxes2.YLim = [0 40];
                hold(app.UIAxes2, 'on')
                for i=1:length(string(app.f))
                    timelength = 1:length(app.tab.(app.f{i}));
                    if app.fileversion == "v2"
                        time = timelength/6000;
                        plot(app.UIAxes2,time,app.tab.(app.f{i}));
                        [app.residual2,app.consumido2,app.total2] = calculateOzone(app,time,app.tab.(app.f{i}));
                    else
                        plot(app.UIAxes2,timelength,app.tab.(app.f{i}));
                        [app.residual2,app.consumido2,app.total2] = calculateOzone(app,timelength,app.tab.(app.f{i}));
                    end
                    app.residual2 = app.residual2 + app.residual2;
                    app.consumido2 = app.consumido2 + app.consumido2;
                    app.total2 = app.total2 + app.total2;
                end
                hold(app.UIAxes2, 'off');
            else
                plot(app.UIAxes2, 0);
                app.UIAxes2.YLim = [0 40];
                %                 app.UIAxes2.XLim = [0 length(app.tab.(app.f{value}))];
                timelength = 1:length(app.tab.(value));
                if app.fileversion == "v2"
                    time = timelength/6000;
                    plot(app.UIAxes2, time,app.tab.(value));
                    [app.residual2,app.consumido2,app.total2] = calculateOzone(app,time,app.tab.(value));
                else
                    plot(app.UIAxes2, timelength,app.tab.(value));
                    [app.residual2,app.consumido2,app.total2] = calculateOzone(app,timelength,app.tab.(value));
                end
                
            end
            DropDownOzonogramaValueChanged(app);
        end


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            % Visibilidad de TabsGroups apagada
            app.TabGroupCinetica.Visible = 'off';
            app.TabGroupOzonograma.Visible = "off";
            app.TabGroupEspectro.Visible = "off";
            app.TabGroupBiorreaccion.Visible = "off";
            app.TabGroupReporte.Visible = "off";
            
            %             screenSize = get(groot,'ScreenSize');
            %             screenWidth = screenSize(3);
            %             screenHeight = screenSize(4);
            %             %             left = screenWidth*0.1;
            %             %             bottom = screenHeight*0.1;
            %             width = screenWidth*0.9;
            %             height = screenHeight*0.85;
            %             width = screenWidth;
            %             height = screenHeight;
            %
            %             left = width*0.05;
            %             bottom = height*0.08;
            %             drawnow;
            %             app.UIFigure.Position = [left bottom width height];
            
            app.residual2 = 0;
            app.consumido2 = 0;
            app.total2 = 0;
        end

        % Button pushed function: CineticaButton
        function CineticaButtonPushed(app, event)
            app.TabGroupCinetica.Visible = 'on';
            app.TabGroupCinetica.Position = app.TabGroup.Position;
            
            monitorSay(app,"red","","",0);
            
            app.IniciarButton.Enable = "off";
            app.GuardarCineticaButton.Enable = "off";
            app.DropDownCinetica.Enable = "off";
            
            % app.UIAxes.XLabel.Color = 'green';
            app.UIAxes.YLim = [0 40];
            app.UIAxes.XLim = [0 app.TiempoEditField.Value];
            app.UIAxes.XColor = 'green';
            app.UIAxes.YColor = 'g';
            app.UIAxes.XLabel.Interpreter = 'tex';
            app.UIAxes.YLabel.Interpreter = 'tex';
            app.UIAxes.XLabel.String = 'Tiempo (min)';
            app.UIAxes.YLabel.String = "Concentración [g/Nm^{3}]";
            
            app.gNm3Label.Interpreter = 'latex';
            app.gNm3Label.FontSize = 16;
            
            app.Label.Text = "------";
            app.Label_2.Text = "------";
            app.Label_3.Text = "------";
            
            UnidadesTiempoDropDownValueChanged(app)
            % app.ArduinoDropDown.Position = [73 522 95 22];
        end

        % Button pushed function: OzonogramaButton
        function OzonogramaButtonPushed(app, event)
            app.TabGroupOzonograma.Visible = 'on';
            app.TabGroupOzonograma.Position = app.TabGroup.Position;
            
            monitorSay2(app,"green","","",0);
            
            app.UIAxes2.XGrid = "on";
            app.UIAxes2.YGrid = "on";
            app.UIAxes2.YLim = [0 40];
            %             app.UIAxes2.XLim = [0 1];
            app.UIAxes2.XColor = "green";
            app.UIAxes2.YColor = "green";
            app.UIAxes2.XLabel.Interpreter = 'tex';
            app.UIAxes2.YLabel.Interpreter = 'tex';
            app.UIAxes2.XLabel.String = 'Tiempo (min)';
            app.UIAxes2.YLabel.String = "Concentración [g/Nm^{3}]";
            
            app.DropDownOzonograma.Enable = "off";
            app.Label_4.Text = "------";
            app.Label_5.Text = "------";
            app.Label_6.Text = "------";
            
            app.GuardarOzonogramaButton.Enable = "off";
        end

        % Button pushed function: EspectroButton
        function EspectroButtonPushed(app, event)
            app.TabGroupEspectro.Visible = 'on';
            app.TabGroupEspectro.Position = app.TabGroup.Position;
        end

        % Button pushed function: BiorreaccionButton
        function BiorreaccionButtonPushed(app, event)
            app.TabGroupBiorreaccion.Visible = 'on';
            app.TabGroupBiorreaccion.Position = app.TabGroup.Position;
        end

        % Button pushed function: ReporteButton
        function ReporteButtonPushed(app, event)
            app.TabGroupReporte.Visible = 'on';
            app.TabGroupReporte.Position = app.TabGroup.Position;
        end

        % Button pushed function: BuscarButton
        function BuscarButtonPushed(app, event)
            app.ports = {};
            app.ArduinoDropDown.Items = {};
            app.ports = char(serialportlist);
            %             disp("Puertos: ")
            %             disp(app.ports)
            if isempty(app.ports)
                app.ArduinoDropDown.Items = [app.ArduinoDropDown.Items];
                monitorSay(app,"red",">> No se detectaron puertos serie","",1.5);
                app.IniciarButton.Enable = "off";
                clear app.a
                app.a = [];
            else
                app.ArduinoDropDown.Items = { app.ports};
                monitorSay(app,"green",">> Listo!","",2);
                app.IniciarButton.Enable = "on";
                %                 disp("Items: ")
                %                 disp(app.ArduinoDropDown.Items)
            end
        end

        % Value changed function: UnidadesTiempoDropDown
        function UnidadesTiempoDropDownValueChanged(app, event)
            value = app.UnidadesTiempoDropDown.Value;
            if value == "min"
                app.UIAxes.XLabel.String = "Tiempo (min)";
            end
            if value == "hr"
                app.UIAxes.XLabel.String = "Tiempo (hr)";
            end
            if value == "seg"
                app.UIAxes.XLabel.String = "Tiempo (seg)";
            end
        end

        % Value changed function: TiempoEditField
        function TiempoEditFieldValueChanged(app, event)
            app.UIAxes.XLim = [0 app.TiempoEditField.Value];
            UnidadesTiempoDropDownValueChanged(app)
        end

        % Value changed function: IniciarButton
        function IniciarButtonValueChanged(app, event)
            value = app.IniciarButton.Value;
            app.ports = char(serialportlist);
            app.Label.Text = "-------";
            app.Label_2.Text = "-------";
            app.Label_3.Text = "-------";
            app.DropDownCinetica.Enable = "off";
            if value == 1 && isempty(app.ports) == 1
                monitorSay(app,"red",">> No se detectaron puertos serie.","",2);
                app.IniciarButton.Value = 0;
                app.IniciarButton.Enable = "off";
                app.GuardarCineticaButton.Enable = "off";
                clear app.a;
                app.a = [];
            elseif value == 1 && isempty(app.ports) == 0
                %                 app.IniciarButton.Enable = "off";
                app.BuscarButton.Enable = "off";
                app.GuardarCineticaButton.Enable = "off";
                app.ArduinoDropDown.Enable = 'off';
                app.TiempoEditField.Enable = 'off';
                app.UnidadesTiempoDropDown.Enable = 'off';
                monitorSay(app,"#edb120","",strcat(">> Conectando Arduino en " + app.ArduinoDropDown.Value + ", espere..."),0);
                if isempty(app.a) == 1
                    app.a = arduino(app.ArduinoDropDown.Value);
                else
                    app.a = app.a;
                    flush(app.a);
                end
                monitorSay(app,"green",">> Listo!","",1);
                monitorSay(app,"#edb120","",">> En progreso...",0);
                app.IniciarButton.Text = "Detener";
                clear app.t app.oz;
                app.t = 0;
                app.oz = 0;
                app.datamatrix = [];
                % app.UIAxes.XLim = [0 app.TiempoEditField.Value];
                plot(app.UIAxes,app.oz);
                flush(app.a)
                % app.UIAxes.XLim = [0 app.TiempoEditField.Value];
                % for i=1:length(app.UIAxes.XTick)
                % app.UIAxes.XTickLabel{i} = num2str(round(app.UIAxes.XTick(i)/60));
                % app.UIAxes.XTickLabel{i} = num2str(app.UIAxes.XTick(i)/60,"%.1f");
                % end
                ArduinoData(app);
            end
        end

        % Button pushed function: GuardarCineticaButton
        function GuardarCineticaButtonPushed(app, event)
            app.GuardarCineticaButton.Enable = 'off';
            monitorSay(app,"#edb120","",">> Guardando...",0);
            [app.residual1,app.consumido1,app.total1] = calculateOzone(app,app.t,app.oz);
            GuardarArchivo(app, app.t,app.oz,app.residual1,app.consumido1,app.total1);
        end

        % Value changed function: DropDownCinetica
        function DropDownCineticaValueChanged(app, event)
            value = app.DropDownCinetica.Value;
            if value == "g/Nm3"
                app.Label.Text = num2str(app.consumido1,"%.2f");
                app.Label_2.Text = num2str(app.residual1,"%.2f");
                app.Label_3.Text = num2str(app.total1,"%.2f");
            end
            if value == "g/L"
                residual = app.residual1/1000;
                consumido = app.consumido1/1000;
                total = app.total1/1000;
                app.Label.Text = num2str(consumido,"%.3f");
                app.Label_2.Text = num2str(residual,"%.3f");
                app.Label_3.Text = num2str(total,"%.3f");
            end
        end

        % Button pushed function: AbrirButton
        function AbrirButtonPushed(app, event)
            app.AbrirButton.Enable = "off";
            app.DropDownOzonograma.Enable = "off";
            app.GuardarOzonogramaButton.Enable = "off";
            
            monitorSay2(app,"#edb120","",">> Cargando archivos...",0);
            VersionButtonGroupSelectionChanged(app);
            [app.tab] = AbrirArchivo(app);
            assignin("base","tabla",app.tab);
            %             [app.residual2, app.consumido2,app.total2] = calculateOzone(app,app.t_o,app.oz_o);
            ArchivoDropDownValueChanged(app);
            DropDownOzonogramaValueChanged(app);
            app.AbrirButton.Enable = "on";
            app.DropDownOzonograma.Enable = "on";
            app.GuardarOzonogramaButton.Enable = "on";
            
            %             disp("Tiempo")
            %             disp(app.t_o)
            %             disp("Concentración")
            %             disp(app.oz_o)
            %             disp("Tabla: ")
            %             disp(app.tab_o)
        end

        % Value changed function: DropDownOzonograma
        function DropDownOzonogramaValueChanged(app, event)
            value = app.DropDownOzonograma.Value;
            if value == "g/Nm3"
                app.Label_4.Text = num2str(app.consumido2,"%.2f");
                app.Label_5.Text = num2str(app.residual2,"%.2f");
                app.Label_6.Text = num2str(app.total2,"%.2f");
            end
            if value == "g/L"
                residual = app.residual2/1000;
                consumido = app.consumido2/1000;
                total = app.total2/1000;
                app.Label_4.Text = num2str(consumido,"%.3f");
                app.Label_5.Text = num2str(residual,"%.3f");
                app.Label_6.Text = num2str(total,"%.3f");
            end
        end

        % Button pushed function: GuardarOzonogramaButton
        function GuardarOzonogramaButtonButtonPushed(app, event)
            app.GuardarOzonogramaButton.Enable = 'off';
            monitorSay2(app,"#edb120","",">> Guardando...",0);
            [app.residual2,app.consumido2,app.total2] = calculateOzone(app,app.t_o,app.oz_o);
            GuardarArchivo(app,app.t_o,app.oz_o,app.residual2,app.consumido2,app.total2);
        end

        % Value changed function: ArchivoDropDown
        function ArchivoDropDownValueChanged(app, event)
            app.ArchivoValue = app.ArchivoDropDown.Value;
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            if value == 1
                app.UIAxes2.XGrid = "on";
                app.UIAxes2.YGrid = "on";
            end
            if value == 0
                app.UIAxes2.XGrid = "off";
                app.UIAxes2.YGrid = "off";
            end
        end

        % Selection changed function: VersionButtonGroup
        function VersionButtonGroupSelectionChanged(app, event)
            app.fileversion= app.VersionButtonGroup.SelectedObject;
            %             if value.Text == "v2"
            %                 disp("Seleccionado la Version 2")
            %             elseif value.Text == "v3"
            %                 disp("Seleccionado la Version 3")
            %             end
            %             disp(value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ArchivoMenu = uimenu(app.UIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.NuevoMenu = uimenu(app.ArchivoMenu);
            app.NuevoMenu.Text = 'Nuevo';
            app.CineticaOzonizacionMenu = uimenu(app.NuevoMenu);
            app.CineticaOzonizacionMenu.Text = 'Cinética Ozonización';
            app.VisorOzonogramaMenu = uimenu(app.NuevoMenu);
            app.VisorOzonogramaMenu.Text = 'Visor Ozonograma';
            app.VisorEspectroUVVisMenu = uimenu(app.NuevoMenu);
            app.VisorEspectroUVVisMenu.Text = 'Visor EspectroUV-Vis';
            app.BiorreaccionMenu = uimenu(app.NuevoMenu);
            app.BiorreaccionMenu.Text = 'Biorreacción';
            app.ReporteMenu = uimenu(app.NuevoMenu);
            app.ReporteMenu.Text = 'Reporte';
            app.AbrirMenu = uimenu(app.ArchivoMenu);
            app.AbrirMenu.Text = 'Abrir';
            app.OzonogramaMenu = uimenu(app.AbrirMenu);
            app.OzonogramaMenu.Text = 'Ozonograma';
            app.EspectroUVVisMenu = uimenu(app.AbrirMenu);
            app.EspectroUVVisMenu.Text = 'EspectroUV-Vis';
            app.BiorreaccionMenu2 = uimenu(app.AbrirMenu);
            app.BiorreaccionMenu2.Text = 'Biorreacción';
            app.GuardarMenu = uimenu(app.ArchivoMenu);
            app.GuardarMenu.Text = 'Guardar';
            app.GuardarcomoMenu = uimenu(app.ArchivoMenu);
            app.GuardarcomoMenu.Text = 'Guardar como';
            app.SalirMenu = uimenu(app.ArchivoMenu);
            app.SalirMenu.Text = 'Salir';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CineticaOzonizacionTab = uitab(app.TabGroup);
            app.CineticaOzonizacionTab.Title = 'Cinética Ozonización';
            app.CineticaButton = uibutton(app.CineticaOzonizacionTab, 'push');
            app.CineticaButton.ButtonPushedFcn = createCallbackFcn(app, @CineticaButtonPushed, true);
            app.CineticaButton.Text = 'Cinética';
            app.HTML1 = uihtml(app.CineticaOzonizacionTab);
            app.HTML1.HTMLSource = 'logo.html';
            app.CrearnuevoLabel_14 = uilabel(app.CineticaOzonizacionTab);
            app.CrearnuevoLabel_14.Text = 'Crear nuevo:';
            app.TabGroupCinetica = uitabgroup(app.CineticaOzonizacionTab);
            app.TabGroupCinetica.Visible = 'off';
            app.CineticaTab = uitab(app.TabGroupCinetica);
            app.CineticaTab.Title = 'Cinética';
            app.MonitorLamp = uilamp(app.CineticaTab);
            app.MonitorLabel = uilabel(app.CineticaTab);
            app.MonitorLabel.Text = '>> Monitor Label';
            app.Panel = uipanel(app.CineticaTab);
            app.ArduinoDropDownLabel = uilabel(app.Panel);
            app.ArduinoDropDownLabel.Text = 'Arduino';
            app.ArduinoDropDown = uidropdown(app.Panel);
            app.ArduinoDropDown.Items = {};
            app.ArduinoDropDown.Value = {};
            app.BuscarButton = uibutton(app.Panel, 'push');
            app.BuscarButton.ButtonPushedFcn = createCallbackFcn(app, @BuscarButtonPushed, true);
            app.BuscarButton.Text = 'Buscar';
            app.Panel_2 = uipanel(app.CineticaTab);
            app.UnidadesTiempoDropDown = uidropdown(app.Panel_2);
            app.UnidadesTiempoDropDown.Items = {'min'};
            app.UnidadesTiempoDropDown.ValueChangedFcn = createCallbackFcn(app, @UnidadesTiempoDropDownValueChanged, true);
            app.UnidadesTiempoDropDown.Value = 'min';
            app.TiempoEditFieldLabel = uilabel(app.Panel_2);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoEditField = uieditfield(app.Panel_2, 'numeric');
            app.TiempoEditField.ValueChangedFcn = createCallbackFcn(app, @TiempoEditFieldValueChanged, true);
            app.TiempoEditField.Value = 60;
            app.GananciaSpinnerLabel = uilabel(app.Panel_2);
            app.GananciaSpinnerLabel.Text = 'Ganancia';
            app.GananciaSpinner = uispinner(app.Panel_2);
            app.GananciaSpinner.Step = 0.0001;
            app.GananciaSpinner.ValueDisplayFormat = '%.4f';
            app.GananciaSpinner.Value = 0.1674;
            app.Panel_3 = uipanel(app.CineticaTab);
            app.DisplayEditField = uieditfield(app.Panel_3, 'numeric');
            app.DisplayEditField.Editable = 'off';
            app.IniciarButton = uibutton(app.Panel_3, 'state');
            app.IniciarButton.ValueChangedFcn = createCallbackFcn(app, @IniciarButtonValueChanged, true);
            app.IniciarButton.Text = 'Iniciar';
            app.gNm3Label = uilabel(app.Panel_3);
            app.gNm3Label.Text = '$g/Nm^{3}$';
            app.Panel_4 = uipanel(app.CineticaTab);
            app.GuardarCineticaButton = uibutton(app.Panel_4, 'push');
            app.GuardarCineticaButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarCineticaButtonPushed, true);
            app.GuardarCineticaButton.Text = 'Guardar';
            app.Panel_5 = uipanel(app.CineticaTab);
            app.ConsumidoLabel = uilabel(app.Panel_5);
            app.ConsumidoLabel.Text = 'Consumido:';
            app.ResidualLabel = uilabel(app.Panel_5);
            app.ResidualLabel.Text = 'Residual:';
            app.TotalLabel = uilabel(app.Panel_5);
            app.TotalLabel.Text = 'Total:';
            app.Label = uilabel(app.Panel_5);
            app.Label.Text = '1252.34';
            app.Label_2 = uilabel(app.Panel_5);
            app.Label_2.Text = '1252.34';
            app.Label_3 = uilabel(app.Panel_5);
            app.Label_3.Text = '1252.34';
            app.DropDownCinetica = uidropdown(app.Panel_5);
            app.DropDownCinetica.Items = {'g/Nm3', 'g/L'};
            app.DropDownCinetica.ValueChangedFcn = createCallbackFcn(app, @DropDownCineticaValueChanged, true);
            app.DropDownCinetica.Value = 'g/Nm3';
            app.UIAxes = uiaxes(app.CineticaTab);
            app.OzonogramaTab = uitab(app.TabGroup);
            app.OzonogramaTab.Title = 'Ozonograma';
            app.OzonogramaButton = uibutton(app.OzonogramaTab, 'push');
            app.OzonogramaButton.ButtonPushedFcn = createCallbackFcn(app, @OzonogramaButtonPushed, true);
            app.OzonogramaButton.Text = 'Ozonograma';
            app.CrearnuevoLabel_15 = uilabel(app.OzonogramaTab);
            app.CrearnuevoLabel_15.Text = 'Crear nuevo:';
            app.HTML2 = uihtml(app.OzonogramaTab);
            app.HTML2.HTMLSource = 'logo.html';
            app.TabGroupOzonograma = uitabgroup(app.OzonogramaTab);
            app.OzonogramaTab2 = uitab(app.TabGroupOzonograma);
            app.OzonogramaTab2.Title = 'Ozonograma';
            app.MonitorLamp_2 = uilamp(app.OzonogramaTab2);
            app.MonitorLabel_2 = uilabel(app.OzonogramaTab2);
            app.MonitorLabel_2.Text = '>> Monitor Label';
            app.Panel_6 = uipanel(app.OzonogramaTab2);
            app.GuardarOzonogramaButton = uibutton(app.Panel_6, 'push');
            app.GuardarOzonogramaButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarOzonogramaButtonButtonPushed, true);
            app.GuardarOzonogramaButton.Text = 'Guardar';
            app.Panel_7 = uipanel(app.OzonogramaTab2);
            app.AbrirButton = uibutton(app.Panel_7, 'push');
            app.AbrirButton.ButtonPushedFcn = createCallbackFcn(app, @AbrirButtonPushed, true);
            app.AbrirButton.Text = 'Abrir';
            app.VersionButtonGroup = uibuttongroup(app.Panel_7);
            app.VersionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VersionButtonGroupSelectionChanged, true);
            app.v2Button = uiradiobutton(app.VersionButtonGroup);
            app.v2Button.Text = 'v2';
            app.v2Button.Value = true;
            app.v3Button = uiradiobutton(app.VersionButtonGroup);
            app.v3Button.Text = 'v3';
            app.Panel_8 = uipanel(app.OzonogramaTab2);
            app.ColordelineaDropDownLabel = uilabel(app.Panel_8);
            app.ColordelineaDropDownLabel.Text = 'Color de linea';
            app.ColordelineaDropDown = uidropdown(app.Panel_8);
            app.ColordelineaDropDown.Items = {'azul', 'naranja', 'amarillo', 'morado', 'verde', 'cian', 'rojo'};
            app.ColordelineaDropDown.Value = 'azul';
            app.GridCheckBox = uicheckbox(app.Panel_8);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.GridCheckBox.Value = true;
            app.Panel_9 = uipanel(app.OzonogramaTab2);
            app.ConsumidoLabel_2 = uilabel(app.Panel_9);
            app.ConsumidoLabel_2.Text = 'Consumido:';
            app.ResidualLabel_2 = uilabel(app.Panel_9);
            app.ResidualLabel_2.Text = 'Residual:';
            app.TotalLabel_2 = uilabel(app.Panel_9);
            app.TotalLabel_2.Text = 'Total:';
            app.Label_4 = uilabel(app.Panel_9);
            app.Label_4.Text = '1252.34';
            app.Label_5 = uilabel(app.Panel_9);
            app.Label_5.Text = '1252.34';
            app.Label_6 = uilabel(app.Panel_9);
            app.Label_6.Text = '1252.34';
            app.DropDownOzonograma = uidropdown(app.Panel_9);
            app.DropDownOzonograma.Items = {'g/Nm3', 'g/L'};
            app.DropDownOzonograma.ValueChangedFcn = createCallbackFcn(app, @DropDownOzonogramaValueChanged, true);
            app.DropDownOzonograma.Value = 'g/Nm3';
            app.TitleEditField = uieditfield(app.OzonogramaTab2, 'text');
            app.TitleEditField.Value = 'Titulo (opcional)';
            app.TitleEditField_2 = uieditfield(app.OzonogramaTab2, 'text');
            app.TitleEditField_2.Value = 'Subtitulo (opcional)';
            app.ArchivoDropDown = uidropdown(app.OzonogramaTab2);
            app.ArchivoDropDown.Items = {};
            app.ArchivoDropDown.ValueChangedFcn = createCallbackFcn(app, @ArchivoDropDownValueChanged, true);
            app.ArchivoDropDown.Value = {};
            app.UIAxes2 = uiaxes(app.OzonogramaTab2);
            app.EspectroUVTab = uitab(app.TabGroup);
            app.EspectroUVTab.Title = 'Espectro UV-Vis';
            app.EspectroButton = uibutton(app.EspectroUVTab, 'push');
            app.EspectroButton.ButtonPushedFcn = createCallbackFcn(app, @EspectroButtonPushed, true);
            app.EspectroButton.Text = 'Espectro UV-Vis';
            app.CrearnuevoLabel_16 = uilabel(app.EspectroUVTab);
            app.CrearnuevoLabel_16.Text = 'Crear nuevo:';
            app.HTML3 = uihtml(app.EspectroUVTab);
            app.HTML3.HTMLSource = 'logo.html';
            app.TabGroupEspectro = uitabgroup(app.EspectroUVTab);
            app.EspectroTab = uitab(app.TabGroupEspectro);
            app.EspectroTab.Title = 'Espectro UV-Vis';
            app.BiorreaccionTab = uitab(app.TabGroup);
            app.BiorreaccionTab.Title = 'Biorreacción';
            app.BiorreaccionButton = uibutton(app.BiorreaccionTab, 'push');
            app.BiorreaccionButton.ButtonPushedFcn = createCallbackFcn(app, @BiorreaccionButtonPushed, true);
            app.BiorreaccionButton.Text = 'Biorreacción';
            app.CrearnuevoLabel_17 = uilabel(app.BiorreaccionTab);
            app.CrearnuevoLabel_17.Text = 'Crear nuevo:';
            app.HTML4 = uihtml(app.BiorreaccionTab);
            app.HTML4.HTMLSource = 'logo.html';
            app.TabGroupBiorreaccion = uitabgroup(app.BiorreaccionTab);
            app.BioreaccionTab = uitab(app.TabGroupBiorreaccion);
            app.BioreaccionTab.Title = 'Biorreacción';
            app.ReporteTab = uitab(app.TabGroup);
            app.ReporteTab.Title = 'Reporte';
            app.ReporteButton = uibutton(app.ReporteTab, 'push');
            app.ReporteButton.ButtonPushedFcn = createCallbackFcn(app, @ReporteButtonPushed, true);
            app.ReporteButton.Text = 'Reporte';
            app.CrearnuevoLabel_18 = uilabel(app.ReporteTab);
            app.CrearnuevoLabel_18.Text = 'Crear nuevo:';
            app.HTML5 = uihtml(app.ReporteTab);
            app.HTML5.HTMLSource = 'logo.html';
            app.TabGroupReporte = uitabgroup(app.ReporteTab);
            app.ReporteTab5 = uitab(app.TabGroupReporte);
            app.ReporteTab5.Title = 'Reporte';
            app.InstruccionesTab = uitab(app.TabGroup);
            app.InstruccionesTab.Title = 'Instrucciones';
            app.HTML7 = uihtml(app.InstruccionesTab);
            app.HTML7.HTMLSource = 'logo.html';
            app.HTML6 = uihtml(app.InstruccionesTab);
            app.HTML6.HTMLSource = 'instructions.html';
            app.AcercadeTab = uitab(app.TabGroup);
            app.AcercadeTab.Title = 'Acerca de';
            app.HTML9 = uihtml(app.AcercadeTab);
            app.HTML9.HTMLSource = 'about.html';
            app.HTML8 = uihtml(app.AcercadeTab);
            app.HTML8.HTMLSource = 'logo.html';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = laiiqautilities_win64

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end